classdef select_threshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        Axes_nPart            matlab.ui.control.UIAxes
        Run                   matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        AxesArea              matlab.ui.control.UIAxes
        ThresholdSliderLabel  matlab.ui.control.Label
        ThresholdSlider       matlab.ui.control.Slider
        UIAxes2               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        I = [];
        thresholds = [];
        areas = [];
        num_particles = [];
        
    end
    
    methods (Access = private)
        
        function plot_thresh(app, current)
            
            spots_logical = app.I>current;
            imagesc(app.UIAxes2, bwareaopen(spots_logical,3,8));
            axis(app.UIAxes2, 'tight', 'equal');
            colormap(app.UIAxes2, 'gray');
            
            [~,pos_dot] = min(abs(app.thresholds-current));
            
            hold(app.Axes_nPart, 'on')
            temp = app.Axes_nPart.Children;
            delete(temp(1))
            semilogy(app.Axes_nPart, app.thresholds(pos_dot), app.num_particles(pos_dot), '.g', 'MarkerSize', 20)
            hold(app.Axes_nPart, 'off')
            
            hold(app.AxesArea, 'on')
            temp = app.AxesArea.Children;
            delete(temp(1))
            semilogy(app.AxesArea, app.thresholds(pos_dot), app.areas(pos_dot), '.g', 'MarkerSize', 20)
            hold(app.AxesArea, 'off')
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Run
        function RunButtonPushed(app, event)
            [file,selpath] = uigetfile('*.tif','Select the TIFF image-file');
            file_in=strcat(selpath,file);
            I_int=imread(file_in,1);
            
            h=fspecial('gaussian',[3 3],1);
            SE = strel('square',2);
            app.I = imfilter(double(I_int),h,'same','conv');
            min_perc = prctile(app.I(:), 10);
            max_perc = prctile(app.I(:), 99.9);
            k = 1;
            
            imagesc(app.UIAxes, app.I);
            axis(app.UIAxes, 'tight', 'equal');
            caxis(app.UIAxes, [prctile(app.I(:), 10), max_perc])
            
            for i = linspace(min_perc, max_perc, 100)
                spots_logical = app.I>i;
                spots_logical = bwareaopen(spots_logical,3,8);
                Lh = imclose(spots_logical,SE);
                s = regionprops(Lh,app.I,'Area');
                num_part(k) = length(s);
                area(k) = mean([s.Area]);
                thresh(k) = i;
                k = k+1;
            end
            
            app.thresholds = thresh;
            app.areas = area;
            app.num_particles = num_part;
            
            semilogy(app.Axes_nPart, thresh, num_part,'.')
            hold(app.Axes_nPart, 'on')
            semilogy(app.Axes_nPart, thresh, movmean(num_part,5))
            semilogy(app.Axes_nPart, thresh(1), num_part(1), '.')
            hold(app.Axes_nPart, 'off')

            semilogy(app.AxesArea,thresh, area,'.')
            hold(app.AxesArea, 'on')
            semilogy(app.AxesArea, thresh, movmean(area,5))
            semilogy(app.AxesArea, thresh(1), area(1), '.')
            hold(app.AxesArea, 'off')
            
            plot_thresh(app, max_perc/2)
            
            app.ThresholdSlider.Enable = true;
            app.ThresholdSlider.Limits = [min_perc max_perc];
            app.ThresholdSlider.Value = max_perc/2;
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            plot_thresh(app, value)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {321, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Axes_nPart = uiaxes(app.LeftPanel);
            title(app.Axes_nPart, 'Number particles')
            xlabel(app.Axes_nPart, 'Threshold level')
            ylabel(app.Axes_nPart, '')
            app.Run = uibutton(app.LeftPanel, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = 'Run';
            app.RightPanel = uipanel(app.GridLayout);
            app.AxesArea = uiaxes(app.RightPanel);
            title(app.AxesArea, 'Area')
            xlabel(app.AxesArea, 'Threshold level')
            ylabel(app.AxesArea, '')
            app.ThresholdSliderLabel = uilabel(app.RightPanel);
            app.ThresholdSliderLabel.Enable = 'off';
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.RightPanel);
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Enable = 'off';
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = select_threshold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end