classdef Main_UI_v3_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        BatchCheckBox                   matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        DatapathEditFieldLabel          matlab.ui.control.Label
        DatapathEditField               matlab.ui.control.EditField
        ProminencehighEditFieldLabel    matlab.ui.control.Label
        ProminencehighEditField         matlab.ui.control.NumericEditField
        prominencedetachEditFieldLabel  matlab.ui.control.Label
        prominencedetachEditField       matlab.ui.control.NumericEditField
        TimeframeEditFieldLabel         matlab.ui.control.Label
        TimeframeEditField              matlab.ui.control.NumericEditField
        SearchButton                    matlab.ui.control.Button
        ParallelEditFieldLabel          matlab.ui.control.Label
        ParallelEditField               matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        LoadsettingsButton              matlab.ui.control.Button
        FinetuneTab                     matlab.ui.container.Tab
        StartEditFieldLabel             matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        MinlengthEditFieldLabel         matlab.ui.control.Label
        MinlengthEditField              matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        MaxmovementEditFieldLabel       matlab.ui.control.Label
        MaxmovementEditField            matlab.ui.control.NumericEditField
        FramebufferEditFieldLabel       matlab.ui.control.Label
        FramebufferEditField            matlab.ui.control.NumericEditField
        BlinkdistanceEditFieldLabel     matlab.ui.control.Label
        BlinkdistanceEditField          matlab.ui.control.NumericEditField
        BlinkdelayEditFieldLabel        matlab.ui.control.Label
        BlinkdelayEditField             matlab.ui.control.NumericEditField
        MinspotsizeEditFieldLabel       matlab.ui.control.Label
        MinspotsizeEditField            matlab.ui.control.NumericEditField
        WholevideoButton                matlab.ui.control.StateButton
        FornerdsTab                     matlab.ui.container.Tab
        AutoCheckBox                    matlab.ui.control.CheckBox
        BlinkingsegmentsEditFieldLabel  matlab.ui.control.Label
        BlinkingsegmentsEditField       matlab.ui.control.NumericEditField
        BleachstepEditFieldLabel        matlab.ui.control.Label
        BleachstepEditField             matlab.ui.control.NumericEditField
        InfoTab                         matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ~isempty(app.DatapathEditField.Value)
                param.time = app.TimeframeEditField.Value;
                param.prev_high = app.ProminencehighEditField.Value;
                param.prev_low = app.prominencedetachEditField.Value/100;
                if app.WholevideoButton.Value
                    param.start = 1;
                    param.end = nan;
                else
                    param.start = app.StartEditField.Value;
                    param.end = app.EndEditField.Value;
                end
                if app.AutoCheckBox.Value
                    param.bleachStep = nan;
                else
                    param.bleachStep = app.BleachstepEditField.Value;
                end
                param.length = app.MinlengthEditField.Value;
                param.jump = app.MaxmovementEditField.Value;
                param.spotSize = app.MinspotsizeEditField.Value;
                param.buffer = app.FramebufferEditField.Value;
                param.batch = app.BatchCheckBox.Value;
                param.parallel = app.ParallelEditField.Value;
                
                param.linkDist = app.BlinkdistanceEditField.Value;
                param.linkDelay = app.BlinkdelayEditField.Value;
                param.path = app.DatapathEditField.Value;
                
                func_main_v3_1(param);
                delete(app)
            end
        end

        % Value changed function: BatchCheckBox
        function BatchCheckBoxValueChanged(app, event)
            value = app.BatchCheckBox.Value;
            if ~isempty(app.DatapathEditField.Value)
                app.DatapathEditField.Value = '';
            end
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            %app.BatchCheckBox
            if app.BatchCheckBox.Value
                temp = uigetdir('', 'Select folder');
                if temp == 0
                    app.DatapathEditField.Value = '';
                else
                    app.DatapathEditField.Value = temp;
                end
            else
                [file, path] = uigetfile('*.tif','Select the TIFF image-file');
                if file==0
                    app.DatapathEditField.Value = '';
                else
                    app.DatapathEditField.Value = strcat(path,'\',file);
                end
            end
        end

        % Value changed function: WholevideoButton
        function WholevideoButtonValueChanged(app, event)
            value = app.WholevideoButton.Value;
            app.StartEditField.Editable = ~value;
            app.EndEditField.Editable = ~value;
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            value = app.AutoCheckBox.Value;
            app.BleachstepEditField.Editable = ~value;
        end

        % Button pushed function: LoadsettingsButton
        function LoadsettingsButtonPushed(app, event)
            [file, path] = uigetfile('*.mat','Select file to copy analysis parameters from');
            if file ~= 0
                load(fullfile(path,file), 'param', 'batch_processing', 'parallel_areas');
                
                app.BatchCheckBox.Value = batch_processing;
                app.ParallelEditField.Value = parallel_areas;
                app.TimeframeEditField.Value = param.dt;
                app.ProminencehighEditField.Value = param.prominence_high;
                app.prominencedetachEditField.Value = param.prominence_low*100;
                app.StartEditField.Value = param.istart;
                try
                if isnan(param.iend)
                    app.EndEditField.Value = 0;
                else
                    app.EndEditField.Value = param.iend;
                end
                catch
                    disp('No end')
                end
                app.MinlengthEditField.Value = param.minTrackLength;
                app.MaxmovementEditField.Value = param.maxJumpDistance;
                app.MinspotsizeEditField.Value = param.minSpotSize;
                app.FramebufferEditField.Value = param.buffer;
                app.BlinkdistanceEditField.Value = param.maxDistanceLink;
                app.BlinkdelayEditField.Value = param.maxDelay;
                if isnan(param.minStep)
                    app.BleachstepEditField.Value = 0;
                    app.AutoCheckBox.Value = true;
                else
                    app.BleachstepEditField.Value = param.minStep;
                    app.AutoCheckBox.Value = false;
                end
                
                clear param
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.BatchCheckBox = uicheckbox(app.MainTab);
            app.BatchCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatchCheckBoxValueChanged, true);
            app.BatchCheckBox.Text = 'Batch';
            app.RunButton = uibutton(app.MainTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DatapathEditFieldLabel = uilabel(app.MainTab);
            app.DatapathEditFieldLabel.Text = 'Data path';
            app.DatapathEditField = uieditfield(app.MainTab, 'text');
            app.ProminencehighEditFieldLabel = uilabel(app.MainTab);
            app.ProminencehighEditFieldLabel.Text = 'Prominence high';
            app.ProminencehighEditField = uieditfield(app.MainTab, 'numeric');
            app.ProminencehighEditField.Limits = [0 Inf];
            app.ProminencehighEditField.Value = 200;
            app.prominencedetachEditFieldLabel = uilabel(app.MainTab);
            app.prominencedetachEditFieldLabel.Text = '% prominence detach';
            app.prominencedetachEditField = uieditfield(app.MainTab, 'numeric');
            app.prominencedetachEditField.Limits = [0 100];
            app.prominencedetachEditField.ValueDisplayFormat = '%.0f';
            app.prominencedetachEditField.Value = 40;
            app.TimeframeEditFieldLabel = uilabel(app.MainTab);
            app.TimeframeEditFieldLabel.Text = 'Time/frame';
            app.TimeframeEditField = uieditfield(app.MainTab, 'numeric');
            app.TimeframeEditField.Limits = [0 Inf];
            app.TimeframeEditField.Value = 10;
            app.SearchButton = uibutton(app.MainTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.ParallelEditFieldLabel = uilabel(app.MainTab);
            app.ParallelEditFieldLabel.Text = 'Parallel';
            app.ParallelEditField = uieditfield(app.MainTab, 'numeric');
            app.ParallelEditField.Limits = [1 Inf];
            app.ParallelEditField.Value = 1;
            app.sLabel = uilabel(app.MainTab);
            app.sLabel.Text = 's';
            app.LoadsettingsButton = uibutton(app.MainTab, 'push');
            app.LoadsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsettingsButtonPushed, true);
            app.LoadsettingsButton.Text = 'Load settings';
            app.FinetuneTab = uitab(app.TabGroup);
            app.FinetuneTab.Title = 'Fine tune';
            app.StartEditFieldLabel = uilabel(app.FinetuneTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.FinetuneTab, 'numeric');
            app.StartEditField.Limits = [1 Inf];
            app.StartEditField.Editable = 'off';
            app.StartEditField.Value = 1;
            app.MinlengthEditFieldLabel = uilabel(app.FinetuneTab);
            app.MinlengthEditFieldLabel.Text = 'Min length';
            app.MinlengthEditField = uieditfield(app.FinetuneTab, 'numeric');
            app.MinlengthEditField.Limits = [0 Inf];
            app.MinlengthEditField.Value = 3;
            app.EndEditFieldLabel = uilabel(app.FinetuneTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.FinetuneTab, 'numeric');
            app.EndEditField.Editable = 'off';
            app.MaxmovementEditFieldLabel = uilabel(app.FinetuneTab);
            app.MaxmovementEditFieldLabel.Text = 'Max movement';
            app.MaxmovementEditField = uieditfield(app.FinetuneTab, 'numeric');
            app.MaxmovementEditField.Limits = [0 Inf];
            app.MaxmovementEditField.Value = 3;
            app.FramebufferEditFieldLabel = uilabel(app.FinetuneTab);
            app.FramebufferEditFieldLabel.Text = 'Frame buffer';
            app.FramebufferEditField = uieditfield(app.FinetuneTab, 'numeric');
            app.FramebufferEditField.Limits = [0 Inf];
            app.FramebufferEditField.Value = 10;
            app.BlinkdistanceEditFieldLabel = uilabel(app.FinetuneTab);
            app.BlinkdistanceEditFieldLabel.Text = 'Blink distance';
            app.BlinkdistanceEditField = uieditfield(app.FinetuneTab, 'numeric');
            app.BlinkdistanceEditField.Limits = [0 Inf];
            app.BlinkdistanceEditField.Value = 1;
            app.BlinkdelayEditFieldLabel = uilabel(app.FinetuneTab);
            app.BlinkdelayEditFieldLabel.Text = 'Blink delay';
            app.BlinkdelayEditField = uieditfield(app.FinetuneTab, 'numeric');
            app.BlinkdelayEditField.Limits = [0 Inf];
            app.BlinkdelayEditField.Value = 50;
            app.MinspotsizeEditFieldLabel = uilabel(app.FinetuneTab);
            app.MinspotsizeEditFieldLabel.Text = 'Min spot size';
            app.MinspotsizeEditField = uieditfield(app.FinetuneTab, 'numeric');
            app.MinspotsizeEditField.Limits = [0 Inf];
            app.MinspotsizeEditField.Value = 3;
            app.WholevideoButton = uibutton(app.FinetuneTab, 'state');
            app.WholevideoButton.ValueChangedFcn = createCallbackFcn(app, @WholevideoButtonValueChanged, true);
            app.WholevideoButton.Text = 'Whole video';
            app.WholevideoButton.Value = true;
            app.FornerdsTab = uitab(app.TabGroup);
            app.FornerdsTab.Title = 'For nerds';
            app.AutoCheckBox = uicheckbox(app.FornerdsTab);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            app.BlinkingsegmentsEditFieldLabel = uilabel(app.FornerdsTab);
            app.BlinkingsegmentsEditFieldLabel.Text = 'Blinking segments';
            app.BlinkingsegmentsEditField = uieditfield(app.FornerdsTab, 'numeric');
            app.BlinkingsegmentsEditField.Value = 20;
            app.BleachstepEditFieldLabel = uilabel(app.FornerdsTab);
            app.BleachstepEditFieldLabel.Text = 'Bleach step';
            app.BleachstepEditField = uieditfield(app.FornerdsTab, 'numeric');
            app.BleachstepEditField.Editable = 'off';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_UI_v3_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end